classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ScreenshotButton             matlab.ui.control.Button
        RotateelementsKnob           matlab.ui.control.Knob
        RotateelementsKnobLabel      matlab.ui.control.Label
        LoadtxtfileButton            matlab.ui.control.Button
        ShowbisectorsCheckBox        matlab.ui.control.CheckBox
        ShowgeneratedpointsCheckBox  matlab.ui.control.CheckBox
        ShowuserpointsCheckBox       matlab.ui.control.CheckBox
        ShowCircle3CheckBox          matlab.ui.control.CheckBox
        ShowCircle2CheckBox          matlab.ui.control.CheckBox
        ShowCircle1CheckBox          matlab.ui.control.CheckBox
        RunconstructionButton        matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        circle1;
        x1;
        y1;
        r;

        circle2;
        x2;
        y2;
        r2;
        
        circle3;
        x3;
        y3;
        r3;
        thirdCenterX;
        thirdCenterY;

        bisector1;
        bisectorX1;
        bisectorY1;

        bisector2;
        bisectorX2;
        bisectorY2;

        bisector3;
        bisectorX3;
        bisectorY3;

        userPoint1;
        firstCenterX;
        firstCenterY;
        
        userPoint2;
        firstCircumferenceX;
        firstCircumferenceY;

        userPoint3;
        secondCenterX;
        secondCenterY;

        userPoint4;
        userPointX1;
        userPointY1;

        userPoint5;
        userPointX2;
        userPointY2;

        userPoint6;
        userPointX3;
        userPointY3;

        genPoint1;
        point1X;
        point1Y;

        genPoint2;
        point2X;
        point2Y;

        genPoint3;
        point3X;
        point3Y;

        genPointX = [];
        intersect_1_3_p;
        intersect_2_3_p;

        coordList = [];
        circlePointsArray;
        initialAngle = 0;
    end
    
    methods (Access = private)
        
        function task2(app,ginputX,ginputY)
            app.firstCenterX = ginputX(1);
            app.firstCenterY = ginputY(1);
            app.firstCircumferenceX = ginputX(2);
            app.firstCircumferenceY = ginputY(2);

            app.coordList(end+1,:) = [ginputX(1),ginputY(1)];
            app.coordList(end+1,:) = [ginputX(2),ginputY(2)];

            app.userPoint1 = plot(app.UIAxes,app.firstCenterX,app.firstCenterY,"Marker","o");
            app.userPoint2 = plot(app.UIAxes,app.firstCircumferenceX,app.firstCircumferenceY,"Marker","o");

            [app.x1,app.y1,app.r] = firstCircle(app.firstCenterX,app.firstCenterY,app.firstCircumferenceX,app.firstCircumferenceY);
            app.circle1 = plot(app.UIAxes,app.x1,app.y1);
        end
    
        function task3(app,ginputX,ginputY)
            app.secondCenterX = ginputX(1);
            app.secondCenterY = ginputY(1);
            
            app.coordList(end+1,:) = [ginputX,ginputY];
    
            app.userPoint3 = plot(app.UIAxes,app.secondCenterX,app.secondCenterY,"Marker","o");
    
            [app.x2,app.y2,app.r2] = secondCircle(app.firstCenterX, app.firstCenterY, app.r , app.secondCenterX, app.secondCenterY);
            app.circle2 = plot(app.UIAxes,app.x2,app.y2);
        end

        function task4_1(app,ginputX,ginputY)    
            app.userPointX1 = ginputX(1);
            app.userPointY1 = ginputY(1);

            app.coordList(end+1,:) = [ginputX,ginputY];

            app.userPoint4 = plot(app.UIAxes,app.userPointX1,app.userPointY1,"Marker","o");

            [app.point1X,app.point1Y] = closestPointCircle(app.circlePointsArray, app.userPointX1, app.userPointY1);      %find points 1 - the closest points on the circumference of circle to entered points 1
            app.genPoint1 = plot(app.UIAxes,app.point1X,app.point1Y,"Marker","*");     
        end

        function task4_2(app,ginputX,ginputY)
            app.userPointX2 = ginputX(1);
            app.userPointY2 = ginputY(1);

            app.coordList(end+1,:) = [ginputX,ginputY];

            app.userPoint5 = plot(app.UIAxes,app.userPointX2,app.userPointY2,"Marker","o");

            [app.point2X,app.point2Y] = closestPointCircle(app.circlePointsArray, app.userPointX2, app.userPointY2);    %find points 2 - the closest points on the circumference of circle to entered points 2
            app.genPoint2 = plot(app.UIAxes,app.point2X,app.point2Y,"Marker","*");                           
            
            if round((app.point1X-app.firstCenterX)^2+(app.point1Y-app.firstCenterY)^2,4) == round((app.point2X-app.firstCenterX)^2+(app.point2Y-app.firstCenterY)^2,4) | round((app.point1X-app.secondCenterX)^2+(app.point1Y-app.secondCenterY)^2,4) == round((app.point2X-app.secondCenterX)^2+(app.point2Y-app.secondCenterY)^2,4)               
                waitfor(errordlg("Both points closest to same circle, please re-run construction enter different points for program to work correctly"));
            end

            [app.bisectorX1,app.bisectorY1] = drawBisector(app.firstCenterX,app.firstCenterY,app.secondCenterX,app.secondCenterY);  %draw bisector of centers of circles 1 and 2
            app.bisector1 = plot(app.UIAxes,app.bisectorX1,app.bisectorY1);
        end
        
        function task4_3(app,ginputX,ginputY)
            app.userPointX3 = ginputX(1);
            app.userPointY3 = ginputY(1);

            app.coordList(end+1,:) = [ginputX,ginputY];

            app.userPoint6 = plot(app.UIAxes,app.userPointX3,app.userPointY3,"Marker","o");

            [app.point3X,app.point3Y] = closestPointLine(app.bisectorX1,app.bisectorY1,app.userPointX3,app.userPointY3);                    %find closest point on the bisector of circles 1 and 2 to entered point
            app.genPoint3 = plot(app.UIAxes,app.point3X,app.point3Y,"Marker","*");                           
            
            [app.bisectorX2,app.bisectorY2] = drawBisector(app.point1X,app.point1Y,app.point2X,app.point2Y);                        %draw bisector between points 1 and 2
            app.bisector2 = plot(app.UIAxes,app.bisectorX2,app.bisectorY2);

            [app.bisectorX3,app.bisectorY3] = drawBisector(app.point1X,app.point1Y,app.point3X,app.point3Y);                        %draw bisector between points 1 and 3
            app.bisector3 = plot(app.UIAxes,app.bisectorX3,app.bisectorY3);
            
            
            [app.thirdCenterX,app.thirdCenterY] = lineIntersection(app.bisectorX2,app.bisectorY2,app.bisectorX3,app.bisectorY3);            %%
            
            [app.x3,app.y3,app.r3] = firstCircle(app.thirdCenterX,app.thirdCenterY,app.point1X,app.point1Y);                            %find and plot third circle
            app.circle3 = plot(app.UIAxes,app.x3,app.y3);
        end

        function task5(app)
            [ num_int_1_3, app.intersect_1_3_p ] = circles_imp_int_2d (app.r, [app.firstCenterX,app.firstCenterY], app.r3, [app.thirdCenterX,app.thirdCenterY] );
            [ num_int_2_3, app.intersect_2_3_p ] = circles_imp_int_2d (app.r2, [app.secondCenterX,app.secondCenterY], app.r3, [app.thirdCenterX,app.thirdCenterY] );
            
            
            for i=1:size(app.intersect_1_3_p,1) 
                app.genPointX = [app.genPointX;plot(app.UIAxes,app.intersect_1_3_p(1,i),app.intersect_1_3_p(2,i),"Marker","*")];
            end
            for i=1:size(app.intersect_2_3_p,1)
                app.genPointX = [app.genPointX;plot(app.UIAxes,app.intersect_2_3_p(1,i),app.intersect_2_3_p(2,i),"Marker","*")];
            end
        end

        function task6(app)
            transformThirdCircleOriginMatrix = [1 0 (0-app.thirdCenterX) ; 0 1 (0-app.thirdCenterY) ; 0 0 1]
            rotationMatrix = rotateBisectorMatrix(app.bisectorX1,app.bisectorY1)
            combinedMatrix = rotationMatrix * transformThirdCircleOriginMatrix

            elementList = [app.circle1,app.circle2,app.circle3,app.bisector1,app.bisector2,app.bisector3];
            pointList = [app.userPoint1;app.userPoint2;app.userPoint3;app.userPoint4;app.userPoint5;app.userPoint6;app.genPoint1;app.genPoint2;app.genPoint3;app.genPointX];
           
            msg = msgbox("Transforming points");
            pause(1);
            close(msg)

            for i=1:size(pointList)
                point = pointList(i);
                x = get(point,"XData");
                y = get(point,"YData");
                m = [x;y;1];
                newPos = combinedMatrix*m;
                
                set(pointList(i), "XData",newPos(1,1))
                set(pointList(i), "YData",newPos(2,1))
            end

            for i=1:length(elementList)
                element = elementList(i);
                XData = get(element,"XData");
                YData = get(element,"YData");
                newXData = [];
                newYData = [];
                for j=1:length(XData)
                    x = XData(j);
                    y = YData(j);
                    m = [x;y;1];
                    newPos = combinedMatrix*m;
                    newXData = [newXData;newPos(1)];
                    newYData = [newYData;newPos(2)];
                end
                set(elementList(i), "XData",newXData)
                set(elementList(i), "YData",newYData)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunconstructionButton
        function RunconstructionButtonPushed(app, event)
            app.coordList = [];
            app.genPointX = [];
            cla(app.UIAxes, 'reset') 
            axis(app.UIAxes, 'equal')
            axis(app.UIAxes,[-10 10 -10 10])
            app.ShowCircle1CheckBox.Value = 1;
            app.ShowCircle2CheckBox.Value = 1;
            app.ShowCircle3CheckBox.Value = 1;
            app.ShowbisectorsCheckBox.Value = 1;
            app.ShowgeneratedpointsCheckBox.Value = 1;
            app.ShowuserpointsCheckBox.Value = 1;
            grid(app.UIAxes, "on")
            hold(app.UIAxes,"on")
            
            [ginputX,ginputY] = ginput(2);
            task2(app,ginputX,ginputY)

            [ginputX,ginputY] = ginput(1);
            task3(app,ginputX,ginputY)

            [ginputX,ginputY] = ginput(1);
            app.circlePointsArray = [app.firstCenterX app.firstCenterY app.r; app.secondCenterX app.secondCenterY app.r2];
            task4_1(app,ginputX,ginputY)

            [ginputX,ginputY] = ginput(1);
            task4_2(app,ginputX,ginputY)

            [ginputX,ginputY] = ginput(1);
            task4_3(app,ginputX,ginputY)

            task5(app)
            task6(app)

            %Task 7
            answer = questdlg("Would you like to save inputted points to a text file?", "Save points", "Yes", "No","Yes");
            if answer == "Yes"
                [file,path] = uiputfile("*.txt");
                drawnow;
                figure(app.UIFigure)
                if path ~= 0
                    toWrite = table(app.coordList);
                    writetable(toWrite,fullfile(path,file),'Delimiter',' ','WriteVariableNames',0);
                end
            end

        end

        % Value changed function: ShowCircle1CheckBox
        function ShowCircle1CheckBoxValueChanged(app, event)
            value = app.ShowCircle1CheckBox.Value;
            if value == 0
                set(app.circle1,"Visible","Off")

            else
                set(app.circle1,"Visible","On")

            end
            
        end

        % Value changed function: ShowCircle2CheckBox
        function ShowCircle2CheckBoxValueChanged(app, event)
            value = app.ShowCircle2CheckBox.Value;
            if value == 0

                set(app.circle2,"Visible","Off")

            else

                set(app.circle2,"Visible","On")

            end
        end

        % Value changed function: ShowCircle3CheckBox
        function ShowCircle3CheckBoxValueChanged(app, event)
            value = app.ShowCircle3CheckBox.Value;
            if value == 0
                set(app.circle3,"Visible","Off")

            else
                set(app.circle3,"Visible","On")

            end
        end

        % Value changed function: ShowbisectorsCheckBox
        function ShowbisectorsCheckBoxValueChanged(app, event)
            value = app.ShowbisectorsCheckBox.Value;
            if value == 0

                set(app.bisector1,"Visible","Off")
                set(app.bisector2,"Visible","Off")
                set(app.bisector3,"Visible","Off")

            else
                set(app.bisector1,"Visible","On")
                set(app.bisector2,"Visible","On")
                set(app.bisector3,"Visible","On")
            end
        end

        % Value changed function: ShowuserpointsCheckBox
        function ShowuserpointsCheckBoxValueChanged(app, event)
            value = app.ShowuserpointsCheckBox.Value;
            if value == 0
                
                set(app.userPoint1,"Visible","Off")
                set(app.userPoint2,"Visible","Off")
                set(app.userPoint3,"Visible","Off")
                set(app.userPoint4,"Visible","Off")
                set(app.userPoint5,"Visible","Off")
                set(app.userPoint6,"Visible","Off")

            else

                set(app.userPoint1,"Visible","On")
                set(app.userPoint2,"Visible","On")
                set(app.userPoint3,"Visible","On")
                set(app.userPoint4,"Visible","On")
                set(app.userPoint5,"Visible","On")
                set(app.userPoint6,"Visible","On")

            end
        end

        % Value changed function: ShowgeneratedpointsCheckBox
        function ShowgeneratedpointsCheckBoxValueChanged(app, event)
            value = app.ShowgeneratedpointsCheckBox.Value;
            if value == 0
                set(app.genPoint1,"Visible","Off")
                set(app.genPoint2,"Visible","Off")
                set(app.genPoint3,"Visible","Off")
                for i=1:size(app.genPointX)
                    set(app.genPointX(i),"Visible","Off")
                end

            else
                set(app.genPoint1,"Visible","On")
                set(app.genPoint2,"Visible","On")
                set(app.genPoint3,"Visible","On")
                for i=1:size(app.genPointX)
                    set(app.genPointX(i),"Visible","On")
                end


            end
        end

        % Button pushed function: LoadtxtfileButton
        function LoadtxtfileButtonPushed(app, event)
            app.coordList = [];
            app.genPointX = [];
            cla(app.UIAxes, 'reset') 
            axis(app.UIAxes, 'equal')
            axis(app.UIAxes,[-10 10 -10 10])
            app.ShowCircle1CheckBox.Value = 1;
            app.ShowCircle2CheckBox.Value = 1;
            app.ShowCircle3CheckBox.Value = 1;
            app.ShowbisectorsCheckBox.Value = 1;
            app.ShowgeneratedpointsCheckBox.Value = 1;
            app.ShowuserpointsCheckBox.Value = 1;
            grid(app.UIAxes, "on")
            hold(app.UIAxes,"on")
            [file,path] = uigetfile("*.txt");
            drawnow;
            figure(app.UIFigure)
            if path ~= 0
                fileData = readtable(fullfile(path,file)); %rows vars
                fileData = fileData{:,:};
                task2(app,fileData(1:2,1),fileData(1:2,2))
                task3(app,fileData(3,1),fileData(3,2))
                app.circlePointsArray = [app.firstCenterX app.firstCenterY app.r; app.secondCenterX app.secondCenterY app.r2];
                task4_1(app,fileData(4,1),fileData(4,2))
                task4_2(app,fileData(5,1),fileData(5,2))
                task4_3(app,fileData(6,1),fileData(6,2))
                task5(app)
                task6(app)
                drawnow;
                figure(app.UIFigure)
            end
        end

        % Value changing function: RotateelementsKnob
        function RotateelementsKnobValueChanging(app, event)
            p = pi;
            changingValue = event.Value*p/180;

            rotationMatrix = [cos(changingValue-app.initialAngle) sin(changingValue-app.initialAngle) 0 ; -1*sin(changingValue-app.initialAngle) cos(changingValue-app.initialAngle) 0 ; 0 0 1];
            
            elementList = [app.circle1,app.circle2,app.circle3,app.bisector1,app.bisector2,app.bisector3];
            pointList = [app.userPoint1;app.userPoint2;app.userPoint3;app.userPoint4;app.userPoint5;app.userPoint6;app.genPoint1;app.genPoint2;app.genPoint3;app.genPointX];

            for i=1:size(pointList)
                point = pointList(i);
                x = get(point,"XData");
                y = get(point,"YData");
                m = [x;y;1];
                newPos = rotationMatrix*m;
                
                set(pointList(i), "XData",newPos(1,1))
                set(pointList(i), "YData",newPos(2,1))
            end

            for i=1:length(elementList)
                element = elementList(i);
                XData = get(element,"XData");
                YData = get(element,"YData");
                newXData = [];
                newYData = [];
                for j=1:length(XData)
                    x = XData(j);
                    y = YData(j);
                    m = [x;y;1];
                    newPos = rotationMatrix*m;
                    newXData = [newXData;newPos(1)];
                    newYData = [newYData;newPos(2)];
                end
                set(elementList(i), "XData",newXData)
                set(elementList(i), "YData",newYData)
            end
            app.initialAngle = changingValue;

        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
            filename = sprintf('%s_%s.png',regexprep("screenshot"," +",""), datestr(now(),"yymmdd_HHMMSS"));
            exportapp(app.UIFigure, filename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [-10 10];
            app.RunconstructionButton = uibutton(app.UIFigure, 'push');
            app.RunconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @RunconstructionButtonPushed, true);
            app.RunconstructionButton.Text = 'Run construction';
            app.ShowCircle1CheckBox = uicheckbox(app.UIFigure);
            app.ShowCircle1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCircle1CheckBoxValueChanged, true);
            app.ShowCircle1CheckBox.Text = 'Show Circle 1';
            app.ShowCircle1CheckBox.Value = true;
            app.ShowCircle2CheckBox = uicheckbox(app.UIFigure);
            app.ShowCircle2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCircle2CheckBoxValueChanged, true);
            app.ShowCircle2CheckBox.Text = 'Show Circle 2';
            app.ShowCircle2CheckBox.Value = true;
            app.ShowCircle3CheckBox = uicheckbox(app.UIFigure);
            app.ShowCircle3CheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCircle3CheckBoxValueChanged, true);
            app.ShowCircle3CheckBox.Text = 'Show Circle 3';
            app.ShowCircle3CheckBox.Value = true;
            app.ShowuserpointsCheckBox = uicheckbox(app.UIFigure);
            app.ShowuserpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowuserpointsCheckBoxValueChanged, true);
            app.ShowuserpointsCheckBox.Text = 'Show user points';
            app.ShowuserpointsCheckBox.Value = true;
            app.ShowgeneratedpointsCheckBox = uicheckbox(app.UIFigure);
            app.ShowgeneratedpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowgeneratedpointsCheckBoxValueChanged, true);
            app.ShowgeneratedpointsCheckBox.Text = 'Show generated points';
            app.ShowgeneratedpointsCheckBox.Value = true;
            app.ShowbisectorsCheckBox = uicheckbox(app.UIFigure);
            app.ShowbisectorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowbisectorsCheckBoxValueChanged, true);
            app.ShowbisectorsCheckBox.Text = 'Show bisectors';
            app.ShowbisectorsCheckBox.Value = true;
            app.LoadtxtfileButton = uibutton(app.UIFigure, 'push');
            app.LoadtxtfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtxtfileButtonPushed, true);
            app.LoadtxtfileButton.Text = 'Load .txt file';
            app.RotateelementsKnobLabel = uilabel(app.UIFigure);
            app.RotateelementsKnobLabel.Text = 'Rotate elements';
            app.RotateelementsKnob = uiknob(app.UIFigure, 'continuous');
            app.RotateelementsKnob.Limits = [0 360];
            app.RotateelementsKnob.ValueChangingFcn = createCallbackFcn(app, @RotateelementsKnobValueChanging, true);
            app.ScreenshotButton = uibutton(app.UIFigure, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Text = 'Screenshot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end